classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SettingsMenu                 matlab.ui.container.Menu
        SelectDatasetMenu            matlab.ui.container.Menu
        Menu_4                       matlab.ui.container.Menu
        Menu_5                       matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        Menu_3                       matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        DisplayTab                   matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        PlayButton                   matlab.ui.control.Button
        AverageAxes                  matlab.ui.control.UIAxes
        AudioAxes                    matlab.ui.control.UIAxes
        SpectrogramAxes              matlab.ui.control.UIAxes
        FeatureViewTab               matlab.ui.container.Tab
        Table                        matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        FilteringSettingsPanel       matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        NdetectionsLabel             matlab.ui.control.Label
        DetectionsLabel              matlab.ui.control.Label
        LabelDropDown                matlab.ui.control.DropDown
        SelectDetectionSpinner       matlab.ui.control.Spinner
        SelectDetectionSpinnerLabel  matlab.ui.control.Label
        GridLayout3                  matlab.ui.container.GridLayout
        FilterTypeDrowdown           matlab.ui.control.DropDown
        AddfilterButton              matlab.ui.control.Button
        FilterDropDown               matlab.ui.control.DropDown
        ActiveFiltersPanel           matlab.ui.container.Panel
        FilterPanelGrid              matlab.ui.container.GridLayout
        ActiveFilterGrid             matlab.ui.container.GridLayout
        FilterButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        dl DataLoader
        ds = 1
        maxSpinner = 0
        features
        featureTable
        filtersMin
        filtersMax
        filtersValueText
        filterData
        filteredFeatures
        filterAbs
        currentAudio
        colRangePercentile = 0.5; %percentile in % that is used to determine the filtering range
    end
    
    methods (Access = public)
        
        function loadData(app)
            load(sprintf("store\\poi_%d.mat", app.ds));
            app.dl = DataLoader(0);
            app.dl.auxLoader(infolist, "single");
            load(sprintf("store\\features_%d.mat", app.ds));
            app.features = D;
            load(sprintf("store\\feature_table_%d.mat", app.ds));
            app.featureTable = T;
            app.filteredFeatures = app.featureTable;
            
        end
        
        function updatePlots(app, value)
            c = app.filteredFeatures(value, :);
            fid = c.FileID;
            [s, info, x] = app.dl.loadAuxFromFID(fid);
            t = app.dl.fb.getTime(s);
            f = app.dl.fb.fc;
            pad = 25;
            sidx = max(c.StartIndex-pad, 1);
            eidx = min(c.EndIndex+pad, size(s, 2));
            s = s(:, sidx:eidx);
            tspec = t(sidx:eidx);
            ax = app.SpectrogramAxes;
            imagesc(ax, tspec, f, log(s));
            ylabel(ax, "Frequency (Hz)");
            xlabel(ax, "Time (s)");
            ax.XLim = [min(tspec), max(tspec)];
            ax.YLim = [min(f), max(f)];
            ax.YDir = "normal";
            ax.Title.String = info.name;
            ax.Title.Interpreter = "none";
            ax.YScale = "log";
            ax.YTick = f(1:6:end);
            ax.YTickLabel = string(round(ax.YTick)); 
            tbox = [t(c.StartIndex) t(c.EndIndex) t(c.EndIndex) t(c.StartIndex)];
            fidx = find(f > c.Frequency(1), 1)-1;
            df = max(c.Frequency(end) - c.Frequency(3), f(fidx+1)-f(fidx));
            fprintf("%.2f\n", df)
            fbox = c.Frequency(1) + [df, df, -df, -df]/2;
            fbox = min(fbox, max(f));
            fbox = max(fbox, min(f));
            hold(ax, 'on')
            fill(ax, tbox, fbox, 'r', "FaceAlpha",0.1, "EdgeAlpha",0.8);
            hold(ax, 'off')
            
            ax = app.AudioAxes;
            sidx = floor(tspec(1)*2000)+1;
            eidx = floor(tspec(end)*2000);
            x = x(sidx:eidx);
            app.currentAudio = x;
            tx = (sidx:eidx)/2000;
            plot(ax, tx, x);

            ax = app.AverageAxes;
            plot(ax, f, (c.Spectrum{:}));

        end

        function idx = getSliderIdx(app, event)
            idx = event.Source.Parent.Layout.Row;
        end

        function sliderChanged(app, event)
            idx = app.getSliderIdx(event);
            v1 = app.filtersMin(idx).Value;
            v2 = app.filtersMax(idx).Value;
            if v2 < v1
                app.filtersMax(idx).Value = v1;
                v2 = v1;
            end
            app.filtersValueText(idx).Text = sprintf("%.2f-%.2f", v1, v2);
        end

        function deleteSlider(app, event)
            idx = event.Source.Parent.Layout.Row;
            Nf = numel(app.filtersMax);
            grid = app.ActiveFilterGrid;
            keep = (1:Nf) ~= idx;
            app.filtersMin= app.filtersMin(keep);
            app.filtersMax= app.filtersMax(keep);
            app.filtersValueText = app.filtersValueText(keep);
            app.filterData = app.filterData(keep);
            app.filterAbs = app.filterAbs(keep);
            
            todel = {};
            for i = 1:numel(grid.Children)
                c = grid.Children(i);
                if c.Layout.Row > idx
                    c.Layout.Row  = c.Layout.Row-1;                
                elseif c.Layout.Row == idx
                    todel{end+1} = c;
                end
            end
            for t = todel
                delete(t{1});
            end

            
        end

        function absClicked(app, cbx, event)
            idx = cbx.Parent.Layout.Row;
            app.setSliderLabels(app.filtersMin(idx), event.Source.Value);
            app.setSliderLabels(app.filtersMax(idx), event.Source.Value);
        end

        function setSliderLabels(app, slider, isabs)
            idx = slider.Parent.Layout.Row;
            fdata = app.filterData(idx);
            colrange = ContourFeatures.columnMinMax(app.featureTable, fdata.name, fdata.type, app.colRangePercentile);
            if isabs
                if colrange(1) < 0
                    if colrange(2) < 0
                        colrange = sort(abs(colrange));
                    else
                        colrange = [0 max(abs(colrange))];
                    end
                end
            end
            ntix = 5;
            order = -ceil(log10(abs(colrange(2))))+3;
            tixMaj = linspace(colrange(1), colrange(2), ntix);
            tixMajRound = string(round(tixMaj, order));
            tixMin = linspace(tixMaj(1), tixMaj(end), (ntix-1)*10+1);
            slider.Limits = double(colrange);
            slider.MajorTicks = tixMaj;
            slider.MajorTickLabels = tixMajRound;
            slider.MinorTicks = tixMin;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ds = 1;   
            app.loadData();
            app.FilterDropDown.Items = sort([ContourFeatures.filter_names, ContourFeatures.standalone_filters]);
            app.FilterTypeDrowdown.Items = ContourFeatures.filter_types;
            app.UITable.ColumnName = app.featureTable.Properties.VariableNames;
        end

        % Button pushed function: AddfilterButton
        function AddFilter(app, event)
            fdata.name = string(app.FilterDropDown.Value);
            fdata.type = string(app.FilterTypeDrowdown.Value);
            is_standalone = sum(fdata.name == ContourFeatures.standalone_filters) > 0;
            if is_standalone
                fdata.type = "";
            end
            app.filterData = [app.filterData, fdata];

            maingrid = app.ActiveFilterGrid;
            maingrid.RowHeight = [maingrid.RowHeight, 80];
              
            r = numel(app.filtersMin) + 1;
            
            slidergrid = uigridlayout(maingrid);
            slidergrid.Layout.Row = r;
            slidergrid.Layout.Column = 2;
            slidergrid.Padding = [0 0 0 0];
            slidergrid.RowSpacing = 0;
            slidergrid.ColumnSpacing = 0;
            slidergrid.RowHeight = {35, 35};
            slidergrid.ColumnWidth = {'1x'};

            slidmin = uislider(slidergrid, 'ValueChangedFcn',@(slid,event) app.sliderChanged(event)); 
            slidmin.Layout.Row = 1;
            slidmin.Layout.Column = 1;
            app.filtersMin = [app.filtersMin, slidmin];
            app.setSliderLabels(slidmin, false);

            slidmax = uislider(slidergrid, 'ValueChangedFcn',@(slid,event) app.sliderChanged(event));   
            slidmax.Layout.Row = 2;
            slidmax.Layout.Column = 1;       
            app.filtersMax = [app.filtersMax, slidmax];
            app.setSliderLabels(slidmax, false);
            

            textgrid = uigridlayout(maingrid);
            textgrid.Layout.Row = r;
            textgrid.Layout.Column = 1;
            textgrid.RowHeight = {'1x', '1x'};
            textgrid.ColumnWidth = {'0.5x', '1x'};


            tdesc = uilabel(textgrid);
            tdesc.Text = fdata.name;
            if sum(fdata.name == ContourFeatures.standalone_filters) == 0
                tdesc.Text = tdesc.Text + " " + fdata.type;
            end
            tdesc.Layout.Row = 1;
            tdesc.Layout.Column = 2;
            tdesc.FontSize = 11;
            tdesc.WordWrap = false;
            tdesc.HorizontalAlignment = 'center';
        

            trange = uilabel(textgrid);
            trange.Text = "";
            trange.Layout.Row = 2;
            trange.Layout.Column = 2;
            trange.FontSize = 11;
            trange.WordWrap = false;
            trange.HorizontalAlignment = 'center';
            app.filtersValueText = [app.filtersValueText, trange];

            bdel = uibutton(textgrid, 'ButtonPushedFcn',@(btn, event) app.deleteSlider(event));
            bdel.Icon = "app\\deleteicon.svg";
            bdel.Layout.Row = 1;
            bdel.Layout.Column = 1;
            bdel.Text = "";

            tickabs = uicheckbox(textgrid, 'ValueChangedFcn',@(cbx, event) app.absClicked(cbx, event));
            app.filterAbs = [app.filterAbs, tickabs];
            tickabs.Layout.Row = 2;
            tickabs.Layout.Column = 1;
            tickabs.Text = "Abs";
            tickabs.Value = false;
            
            
        end

        % Button pushed function: FilterButton
        function Filter(app, event)
            Nf = numel(app.filtersMax);
            T = app.featureTable;
            for i = 1:Nf
                v1 = app.filtersMin(i).Value;
                v2 = app.filtersMax(i).Value;
                fdata = app.filterData(i);
                isabs = app.filterAbs(i).Value;
                T = ContourFeatures.filter(T, fdata.name, fdata.type, [v1, v2], isabs);
            end
            app.UITable.Data = T;
            app.filteredFeatures = T;
            app.NdetectionsLabel.Text = string(size(T, 1));
        end

        % Value changed function: SelectDetectionSpinner
        function SelectContour(app, event)
            value = app.SelectDetectionSpinner.Value;
            Nmax = size(app.filteredFeatures, 1);
            if value > Nmax
                app.SelectDetectionSpinner.Value = Nmax;
                value = Nmax;
            end
            if value < 1
                app.SelectDetectionSpinner.Value = 1;
                value = 1;
            end
            app.updatePlots(value);
            
        end

        % Value changed function: LabelDropDown
        function LabelContour(app, event)
            value = app.LabelDropDown.Value;
            
        end

        % Menu selected function: Menu_4
        function DS1Selected(app, event)
            app.ds = 1;
            app.loadData();
        end

        % Menu selected function: Menu_5
        function DS2Selected(app, event)
            app.ds = 2;
            app.loadData();   
        end

        % Menu selected function: SaveMenu
        function Save(app, event)
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            soundsc(app.currentAudio, 2000);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.SelectDatasetMenu = uimenu(app.SettingsMenu);
            app.SelectDatasetMenu.Text = 'Select Dataset';
            app.Menu_4 = uimenu(app.SelectDatasetMenu);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @DS1Selected, true);
            app.Menu_4.Text = '1';
            app.Menu_5 = uimenu(app.SelectDatasetMenu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @DS2Selected, true);
            app.Menu_5.Text = '2';
            app.SaveMenu = uimenu(app.SettingsMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @Save, true);
            app.SaveMenu.Text = 'Save';
            app.Menu_3 = uimenu(app.UIFigure);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FilteringSettingsPanel = uipanel(app.GridLayout);
            app.FilteringSettingsPanel.Title = 'Filtering Settings';
            app.GridLayout2 = uigridlayout(app.FilteringSettingsPanel);
            app.ActiveFiltersPanel = uipanel(app.GridLayout2);
            app.ActiveFiltersPanel.Title = 'Active Filters';
            app.FilterPanelGrid = uigridlayout(app.ActiveFiltersPanel);
            app.FilterButton = uibutton(app.FilterPanelGrid, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @Filter, true);
            app.FilterButton.Text = 'Filter';
            app.ActiveFilterGrid = uigridlayout(app.FilterPanelGrid);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.FilterDropDown = uidropdown(app.GridLayout3);
            app.FilterDropDown.Items = {};
            app.FilterDropDown.Value = {};
            app.AddfilterButton = uibutton(app.GridLayout3, 'push');
            app.AddfilterButton.ButtonPushedFcn = createCallbackFcn(app, @AddFilter, true);
            app.AddfilterButton.Text = 'Add filter';
            app.FilterTypeDrowdown = uidropdown(app.GridLayout3);
            app.FilterTypeDrowdown.Items = {};
            app.FilterTypeDrowdown.Value = {};
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.SelectDetectionSpinnerLabel = uilabel(app.GridLayout4);
            app.SelectDetectionSpinnerLabel.Text = 'Select Detection';
            app.SelectDetectionSpinner = uispinner(app.GridLayout4);
            app.SelectDetectionSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectContour, true);
            app.LabelDropDown = uidropdown(app.GridLayout4);
            app.LabelDropDown.Items = {'None', 'Whale', 'Humpback', 'Southern Right', 'Brydes', 'Other', 'Noise'};
            app.LabelDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelContour, true);
            app.LabelDropDown.Value = 'None';
            app.DetectionsLabel = uilabel(app.GridLayout4);
            app.DetectionsLabel.Text = 'Detections:';
            app.NdetectionsLabel = uilabel(app.GridLayout4);
            app.NdetectionsLabel.Text = 'Ndetections';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.GridLayout6 = uigridlayout(app.DisplayTab);
            app.SpectrogramAxes = uiaxes(app.GridLayout6);
            title(app.SpectrogramAxes, 'Spectrogram')
            xlabel(app.SpectrogramAxes, 'X')
            ylabel(app.SpectrogramAxes, 'Y')
            zlabel(app.SpectrogramAxes, 'Z')
            app.AudioAxes = uiaxes(app.GridLayout6);
            title(app.AudioAxes, 'Audio')
            xlabel(app.AudioAxes, 'X')
            ylabel(app.AudioAxes, 'Y')
            zlabel(app.AudioAxes, 'Z')
            app.AverageAxes = uiaxes(app.GridLayout6);
            title(app.AverageAxes, 'Average Spectrogram')
            xlabel(app.AverageAxes, 'X')
            ylabel(app.AverageAxes, 'Y')
            zlabel(app.AverageAxes, 'Z')
            app.PlayButton = uibutton(app.GridLayout6, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FeatureViewTab = uitab(app.TabGroup);
            app.FeatureViewTab.Title = 'Feature View';
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.GridLayout7 = uigridlayout(app.Table);
            app.UITable = uitable(app.GridLayout7);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end